classdef chan_pruebas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Archivo           matlab.ui.container.Menu
        AbrirarchivoMenu  matlab.ui.container.Menu
        GuardarMenu       matlab.ui.container.Menu
        UITable           matlab.ui.control.Table
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        table
        data
        VariableName
        opts
        ubicacion
        fechaInicio
        dias
        diasTranscurridos
        diasTotales
        BarrilesPetroleo
        BarrilesAgua
        WOR
        PetroleoAcumulado
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Menu selected function: AbrirarchivoMenu
        function AbrirarchivoMenuSelected(app, event)
            [name,direction]=uigetfile('*.csv');
            app.table=[direction,name];
            app.ubicacion=direction;
            Data=readtable(app.table);
            app.data=Data;
            app.UITable.Data=Data;
            app.UITable.ColumnName=Data.Properties.VariableNames;
            app.fechaInicio =Data.Fecha; % Se extraen las fechas
            app.dias=caldiff(app.fechaInicio,"days"); % Se calcula la diferencia entre las fechas en formato de dias
            app.diasTranscurridos=split(app.dias,"days"); % Se convierte de formato dias a numero
            app.diasTranscurridos=[0;app.diasTranscurridos]; % Se agrega el día 0
            app.diasTotales=zeros(1,length(app.diasTranscurridos))'; % Vector de 0 para calculo más rapido
            % Ciclo for que suma los valores anteriores con el actual para sacar el acumulado de días
            for i=2:length(app.diasTotales)
                app.diasTotales(i)=app.diasTranscurridos(i)+sum(app.diasTranscurridos(1:i-1));
            end
            app.BarrilesPetroleo=Data.Produccion_Petroleo; % Se extraen los barriles de petroleo
            app.BarrilesAgua=Data.BAPD; % Se extraen los barriles de agua
            app.WOR=app.BarrilesAgua./app.BarrilesPetroleo; app.WOR(app.WOR==inf)=0; % Relación Agua-Petroleo
            derivada_WOR=gradient(app.WOR,app.diasTotales); % Calculo de la derivada
            app.PetroleoAcumulado=app.BarrilesPetroleo.*app.diasTranscurridos;
            f = fit(app.diasTotales,app.BarrilesPetroleo,'exp1'); % Ajuste exponencial
            f_WOR=fit(app.diasTotales,app.WOR,'poly2'); % Ajuste polinomial
            f_WOR_derivada=fit(app.diasTotales,derivada_WOR,'poly2'); % Ajuste polinomial
            max_produccion=max(app.BarrilesPetroleo);
            pos_max=find(max(app.BarrilesPetroleo));
            max_dias=app.diasTotales(pos_max);
            
            plot(app.UIAxes,app.diasTotales,app.BarrilesPetroleo,'o')
            hold(app.UIAxes)
            plot(app.UIAxes,f)
            scatter(app.UIAxes,max_dias,max_produccion,'red','filled')
            axis(app.UIAxes,'padded')
            xlabel(app.UIAxes,'Días')
            ylabel(app.UIAxes,'Producción de Petróleo (barriles/día)')
            legend(app.UIAxes,'Datos de Producción','Ajuste Exponencial','Máxima producción')
            title(app.UIAxes,'Declinación de Producción de Petróleo')

            plot(app.UIAxes2,app.diasTotales,app.WOR,'o')
            hold(app.UIAxes2)
            plot(app.UIAxes2,f_WOR,'m')
            plot(app.UIAxes2,app.diasTotales,derivada_WOR,'o')
            plot(app.UIAxes2,f_WOR_derivada,'c')
            axis(app.UIAxes2,'padded')
            xlabel(app.UIAxes2,'Días')
            ylabel(app.UIAxes2,'WOR / Derivada del WOR')
            set(app.UIAxes2, 'YScale', 'log')
            set(app.UIAxes2, 'XScale', 'log')
            legend(app.UIAxes2,'Datos WOR','Ajuste WOR','Derivada WOR','Ajuste Derivada')
            title(app.UIAxes2,'Relación Agua-Petróleo (WOR) y su Derivada')

        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            % app.fechaInicio =app.data.Fecha;
            % app.dias=caldiff(app.fechaInicio,"days"); % Se calcula la diferencia entre las fechas en formato de dias
            % app.diasTranscurridos=split(app.dias,"days"); % Se convierte de formato dias a numero
            % app.diasTranscurridos=[0;app.diasTranscurridos]; % Se agrega el día 0
            % app.diasTotales=zeros(1,length(app.diasTranscurridos))'; % Vector de 0 para calculo más rapido
            % for i=2:length(app.diasTotales)
            %     app.diasTotales(i)=app.diasTranscurridos(i)+sum(app.diasTranscurridos(1:i-1));
            % end
            % BarrilesPetroleo=app.data.Produccion_Petroleo; % Se extraen los barriles de petroleo
            % BarrilesAgua=app.data.BAPD; % Se extraen los barriles de agua
            % WOR=BarrilesAgua./BarrilesPetroleo; WOR(WOR==inf)=0; % Relación Agua-Petroleo
            % derivada_WOR=gradient(WOR,app.diasTotales); % Calculo de la derivada
            % PetroleoAcumulado=BarrilesPetroleo.*app.diasTranscurridos;
            % f = fit(app.diasTotales,BarrilesPetroleo,'exp1'); % Ajuste exponencial
            % f_WOR=fit(diasTotales,WOR,'poly2'); % Ajuste polinomial
            % f_WOR_derivada=fit(app.diasTotales,derivada_WOR,'poly2'); % Ajuste polinomial
            T=table(app.fechaInicio,app.diasTranscurridos,app.diasTotales,app.BarrilesPetroleo,app.BarrilesAgua,app.WOR,app.PetroleoAcumulado);
            writetable(T,[app.ubicacion,'fechas_output_matlab.csv']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Archivo = uimenu(app.UIFigure);
            app.Archivo.Text = 'Menu';
            app.AbrirarchivoMenu = uimenu(app.Archivo);
            app.AbrirarchivoMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirarchivoMenuSelected, true);
            app.AbrirarchivoMenu.Text = 'Abrir archivo';
            app.GuardarMenu = uimenu(app.Archivo);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Text = 'Guardar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chan_pruebas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end